classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RunButton                      matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        SystemTab                      matlab.ui.container.Tab
        OutputstobeattackedNoselectionchoosesrandomoutputsListBox  matlab.ui.control.ListBox
        OutputstobeattackedNoselectionchoosesrandomoutputsListBoxLabel  matlab.ui.control.Label
        LoadsystemfromafileemptyfieldusessettingsaboveLabel  matlab.ui.control.Label
        SystemInputFileEditField       matlab.ui.control.EditField
        SystemInputFileEditFieldLabel  matlab.ui.control.Label
        NumberofAttacksSlider          matlab.ui.control.Slider
        NumberofAttacksSliderLabel     matlab.ui.control.Label
        SystemParametersLabel          matlab.ui.control.Label
        NumberofCustomersSlider        matlab.ui.control.Slider
        NumberofCustomersSliderLabel   matlab.ui.control.Label
        SimulationTab                  matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            numCustomers    = app.NumberofCustomersSlider.Value;
            numAttacks      = app.NumberofAttacksSlider.Value;
            inputFileName   = app.SystemInputFileEditField.Value;
            PDMO = PowerDistMO(numCustomers,numAttacks,inputFileName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.NumberofCustomersSliderLabel = uilabel(app.SystemTab);
            app.NumberofCustomersSliderLabel.Text = 'Number of Customers';
            app.NumberofCustomersSlider = uislider(app.SystemTab);
            app.NumberofCustomersSlider.Limits = [0 50];
            app.NumberofCustomersSlider.Value = 5;
            app.SystemParametersLabel = uilabel(app.SystemTab);
            app.SystemParametersLabel.Text = 'System Parameters';
            app.NumberofAttacksSliderLabel = uilabel(app.SystemTab);
            app.NumberofAttacksSliderLabel.Text = 'Number of Attacks';
            app.NumberofAttacksSlider = uislider(app.SystemTab);
            app.NumberofAttacksSlider.Limits = [0 50];
            app.NumberofAttacksSlider.Value = 5;
            app.SystemInputFileEditFieldLabel = uilabel(app.SystemTab);
            app.SystemInputFileEditFieldLabel.Text = 'System Input  File';
            app.SystemInputFileEditField = uieditfield(app.SystemTab, 'text');
            app.LoadsystemfromafileemptyfieldusessettingsaboveLabel = uilabel(app.SystemTab);
            app.LoadsystemfromafileemptyfieldusessettingsaboveLabel.Text = 'Load system from a file (empty field uses settings above)';
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBoxLabel = uilabel(app.SystemTab);
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBoxLabel.Text = {'Outputs to be attacked'; '(No selection  chooses'; 'random outputs)'};
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox = uilistbox(app.SystemTab);
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50'};
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox.Value = '1';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end